classdef test1_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        orLabel                         matlab.ui.control.Label
        ChooseYourEquationLabel         matlab.ui.control.Label
        handleEditField                 matlab.ui.control.EditField
        handleEditFieldLabel            matlab.ui.control.Label
        symEditField                    matlab.ui.control.EditField
        yourequationLabel               matlab.ui.control.Label
        SelectEquationDropDown          matlab.ui.control.DropDown
        SelectEquationDropDownLabel     matlab.ui.control.Label
        fval_BDFTextArea                matlab.ui.control.TextArea
        fval_BDFTextAreaLabel           matlab.ui.control.Label
        N_BDFTextArea                   matlab.ui.control.TextArea
        N_BDFTextAreaLabel              matlab.ui.control.Label
        slider_BDFSlider                matlab.ui.control.Slider
        slider_BDFSliderLabel           matlab.ui.control.Label
        BDFButton                       matlab.ui.control.Button
        fval_PECE3TextArea              matlab.ui.control.TextArea
        fval_PECE3TextAreaLabel         matlab.ui.control.Label
        N_PECE3TextArea                 matlab.ui.control.TextArea
        N_PECE3TextAreaLabel            matlab.ui.control.Label
        slider_PECE3Slider              matlab.ui.control.Slider
        slider_PECE3SliderLabel         matlab.ui.control.Label
        PECE3Button                     matlab.ui.control.Button
        fval_IRKTextArea                matlab.ui.control.TextArea
        fval_IRKTextAreaLabel           matlab.ui.control.Label
        N_IRKTextArea                   matlab.ui.control.TextArea
        N_IRKTextAreaLabel              matlab.ui.control.Label
        slider_IRKSlider                matlab.ui.control.Slider
        slider_IRKSliderLabel           matlab.ui.control.Label
        IRKButton                       matlab.ui.control.Button
        fval_ERK4TextArea               matlab.ui.control.TextArea
        fval_ERK4TextAreaLabel          matlab.ui.control.Label
        N_ERK4TextArea                  matlab.ui.control.TextArea
        N_ERK4TextAreaLabel             matlab.ui.control.Label
        slider_ERK4Slider               matlab.ui.control.Slider
        slider_ERK4SliderLabel          matlab.ui.control.Label
        ERK4Button                      matlab.ui.control.Button
        fval_ERK3TextArea               matlab.ui.control.TextArea
        fval_ERK3TextAreaLabel          matlab.ui.control.Label
        N_ERK3TextArea                  matlab.ui.control.TextArea
        N_ERK3TextAreaLabel             matlab.ui.control.Label
        slider_ERK3Slider               matlab.ui.control.Slider
        slider_ERK3SliderLabel          matlab.ui.control.Label
        ERK3Button                      matlab.ui.control.Button
        fval_ERK2TextArea               matlab.ui.control.TextArea
        fval_ERK2TextAreaLabel          matlab.ui.control.Label
        N_ERK2TextArea                  matlab.ui.control.TextArea
        N_ERK2TextAreaLabel             matlab.ui.control.Label
        slider_ERK2Slider               matlab.ui.control.Slider
        slider_ERK2SliderLabel          matlab.ui.control.Label
        ERK2Button                      matlab.ui.control.Button
        fval_TrapezoidTextArea          matlab.ui.control.TextArea
        fval_TrapezoidTextAreaLabel     matlab.ui.control.Label
        N_TrapezoidTextArea             matlab.ui.control.TextArea
        N_TrapezoidTextAreaLabel        matlab.ui.control.Label
        fval_IEulerTextArea             matlab.ui.control.TextArea
        fval_IEulerTextAreaLabel        matlab.ui.control.Label
        N_IEulerTextArea                matlab.ui.control.TextArea
        N_IEulerTextAreaLabel           matlab.ui.control.Label
        fval_EEulerTextArea             matlab.ui.control.TextArea
        fval_EEulerTextAreaLabel        matlab.ui.control.Label
        N_EEulerTextArea                matlab.ui.control.TextArea
        N_EEulerTextAreaLabel           matlab.ui.control.Label
        sliderTrapezoidSlider           matlab.ui.control.Slider
        sliderTrapezoidSliderLabel      matlab.ui.control.Label
        sliderI_eulerSliderLabel        matlab.ui.control.Label
        sliderI_eulerSlider             matlab.ui.control.Slider
        sliderE_eulerSlider             matlab.ui.control.Slider
        sliderE_eulerSliderLabel        matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        InitialconditionEditField       matlab.ui.control.NumericEditField
        InitialconditionEditFieldLabel  matlab.ui.control.Label
        RightboundaryEditFieldLabel     matlab.ui.control.Label
        RightboundaryEditField          matlab.ui.control.NumericEditField
        LeftboundaryEditField           matlab.ui.control.NumericEditField
        LeftboundaryEditFieldLabel      matlab.ui.control.Label
        TrapezoidButton                 matlab.ui.control.Button
        ImplicitEulerButton             matlab.ui.control.Button
        ExplicitEulerButton             matlab.ui.control.Button
        SolveOdeButton                  matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)   % Global variables
        stringFunction % string function
        symFunction % sym function
        handleFunction % handle function
        TimeInterval % tspan
        NumSteps % N
        Ti % time interval
        Y0 % initial condition
        Ytrue % true slution
        df % derivative
        N_EE % N E_euler changing value
        N_IE % N I_euler changing value
        N_T % N Trapezoid_method changing value
        N_ERK2 % N ERK2 changing value
        N_ERK3 % N ERK4 changing value
        N_ERK4 % N ERK4 changing value
        N_IRK % N IRK changing value
        N_PECE3 % N PECE3 changing value
        N_BDF % N BDF changing value
    end

    methods (Access = private)

        function updateplot(app)

            % Define function

            syms y(t)

            if isequal(app.SelectEquationDropDown.Value,'y*(2+y)*(2-y)')
                equationStr = 'y(t).*(2+y(t)).*(2-y(t))'; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = 'y.*(2+y).*(2-y)'; % handle function
                app.LeftboundaryEditField.Value = 0;
                app.RightboundaryEditField.Value = 2;
                app.InitialconditionEditField.Value = 1;
            elseif isequal(app.SelectEquationDropDown.Value,'y+t')
                equationStr = 'y(t)+t'; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = 'y+t'; % handle function
                app.LeftboundaryEditField.Value = 0;
                app.RightboundaryEditField.Value = 3;
                app.InitialconditionEditField.Value = 1;
            elseif isequal(app.SelectEquationDropDown.Value,'-5*t*y^2+(5/t)-(1/t^2)')
                equationStr = '-5.*t.*y(t).^2+(5./t)-(1./t.^2)'; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = '-5.*t.*y.^2+(5./t)-(1./t.^2)'; % handle function
                app.LeftboundaryEditField.Value = 1;
                app.RightboundaryEditField.Value = 25;
                app.InitialconditionEditField.Value = 1;
            elseif isequal(app.SelectEquationDropDown.Value,'-100*(y-sin(t))')
                equationStr = '-100.*(y(t)-sin(t))'; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = '-100.*(y-sin(t))'; % handle function
                app.LeftboundaryEditField.Value = 0;
                app.RightboundaryEditField.Value = 3;
                app.InitialconditionEditField.Value = 1;
            elseif isequal(app.SelectEquationDropDown.Value,'2*t*y')
                equationStr = '2.*t.*y(t)'; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = '2.*t.*y'; % handle function
                app.LeftboundaryEditField.Value = 0;
                app.RightboundaryEditField.Value = 3;
                app.InitialconditionEditField.Value = 1;
            else
                equationStr = app.symEditField.Value; % string function
                equationSym = str2sym(equationStr); % sym function
                equationHandle = app.handleEditField.Value; % handle function
            end

            app.stringFunction = equationStr; % string function
            app.symFunction = equationSym; % sym function
            app.handleFunction = @(t, y) eval(equationHandle); % handle function

            ode = diff(y)==equationSym;
            app.Y0 = app.InitialconditionEditField.Value;
            cond = y(app.LeftboundaryEditField.Value)==app.Y0; % initial condition

            % true solution
            ySol(t)=dsolve(ode,cond);
            ySol = matlabFunction(ySol(t));
            app.Ytrue = ySol;

            % derivative of f
            syms t y
            fun = str2sym(equationHandle);
            g = diff(fun,y);
            app.df = matlabFunction(g, 'Vars', [t y]);

            % timespan
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % Plot the solution
            plot(app.UIAxes, app.Ti, ySol(app.Ti),'yellow','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            title(app.UIAxes, 'Solution Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Solution y(t)')

        end

        function [y_num,f_val] = E_euler(app)
            t=app.Ti(1);
            y_num=app.Y0;
            len = length(app.Ti);
            for k=1:len-1
                h = app.Ti(k+1) - app.Ti(k);
                y_num = [y_num, y_num(:,k)+h*app.handleFunction(t(k),y_num(:,k))];
                t = [t,t(k)+h];
            end
            f_val = len-1;
        end

        function [y_num,f_val] = I_euler(app)
            u(:,1) = app.Y0;
            f_val = 0;
            for n = 2:length(app.Ti)
                h = app.Ti(n)-app.Ti(n-1);
                [u(:,n),fval] = IE_Newton(app,u(:,n-1),app.Ti(n),h);
                f_val = f_val+fval;
            end
            y_num = u;
        end

        function [v,fcount] = IE_Newton(app,u,t,h)
            maxit = min(1e4,100*length(u));
            abstoll = 1e-5;
            wtemp = u;
            fcount = 0;
            for i = 1:maxit              
                fv = app.handleFunction(t,wtemp);
                J = eye(1)-h*app.df(t,wtemp);   
                rhs = -(wtemp-h*fv-u);
                s = J\rhs;
                w = wtemp+s;
                fcount = fcount+2;
                if norm(w-wtemp)<abstoll         
                    break
                end
                wtemp=w;
            end
            v = w;        
        end

        function [y_num,f_val] = Trapezoid_method(app)
            u(:,1) = app.Y0;
            f_val = 0;
            for n = 2:length(app.Ti)
                h = app.Ti(n)-app.Ti(n-1);
                [u(:,n),fval] = T_Newton(app,u(:,n-1),app.Ti(n),app.Ti(n-1),h);
                f_val = f_val+fval;
            end
            y_num = u;
        end

        function [v,fcount] = T_Newton(app,u,t,t_old,h)
            maxit = min(1e4,100*length(u));
            abstoll = 1e-5;
            wtemp = u;
            f_old = app.handleFunction(t_old,u);
            fcount = 1;
            for i = 1:maxit
                
                fv = app.handleFunction(t,wtemp);
                J = eye(1)-(h/2)*app.df(t,wtemp);

                rhs = -(wtemp-u-(h/2)*(fv+f_old));
                s = J\rhs;
                w = wtemp+s;
                if norm(w-wtemp)<abstoll 
                    
                    break
                end
                wtemp=w;
                fcount = fcount + 2;
            end
            v = w;
            
        end

        function[y_num,f_val] = ERK2(app)
            u(:,1) = app.Y0;
            f_val = 0;
            b1 = 1/2; b2 = 1/2; c2 = 1;
            for n = 2:length(app.Ti)
                h = app.Ti(n) - app.Ti(n-1);
                K1 = app.handleFunction(app.Ti(n-1),u(:,n-1));
                K2 = app.handleFunction(app.Ti(n-1) + h * c2,u(:,n-1) + h*c2*K1);
                u(:,n) = u(:,n-1) + h* (b1*K1 + b2 * K2);
                f_val = f_val + 2;
            end
            y_num = u;
        end

        function[y_num,f_val] = ERK3(app)
            u(:,1) = app.Y0;
            f_val = 0;
            b1 = 1/4; b2 = 1/4; b3 = 1/2; c2 = 2/3; c3 = 2/3;
            A = [0,0,0;
                2/3,0,0;
                1/6,1/2,0];
            for n = 2:length(app.Ti)
                h = app.Ti(n) - app.Ti(n-1);
                K1 = app.handleFunction(app.Ti(n-1),u(:,n-1));
                K2 = app.handleFunction(app.Ti(n-1) + h * c2,u(:,n-1) + h*A(2,1)*K1);
                K3 = app.handleFunction(app.Ti(n-1) + h * c3,u(:,n-1) + h*A(3,1)*K1 + h*A(3,2)*K2);
                u(:,n) = u(:,n-1) + h* (b1*K1 + b2 * K2 + b3 * K3);
                f_val = f_val + 3;
            end
            y_num = u;
        end

        function[y_num,f_val] = ERK4(app)
            u(:,1) = app.Y0;
            f_val = 0;
            b1 = 1/8; b2 = 3/8; b3 = 3/8 ; b4 = 1/8; c2 = 1/3; c3 = 2/3; c4 = 1;
            A = [0,0,0,0;
                1/3,0,0,0;
                -1/3,1,0,0
                1,-1,1,0 ];
            for n = 2:length(app.Ti)
                h = app.Ti(n) - app.Ti(n-1);
                K1 = app.handleFunction(app.Ti(n-1),u(:,n-1));
                K2 = app.handleFunction(app.Ti(n-1) + h * c2,u(:,n-1) + h*A(2,1)*K1);
                K3 = app.handleFunction(app.Ti(n-1) + h * c3,u(:,n-1) + h*A(3,1)*K1 + h*A(3,2)*K2);
                K4 = app.handleFunction(app.Ti(n-1) + h * c4,u(:,n-1) + h*A(4,1)*K1 + h*A(4,2)*K2 + h*A(4,3)*K3);
                u(:,n) =u(:,n-1) + h* (b1*K1 + b2 * K2 + b3 * K3 + b4 * K4);
                f_val = f_val + 4;
            end
            y_num = u;
        end

        function[y_num,f_val] = IRK(app)
            u(1) = app.Y0;
            f_val = 0;
            butcher.b = [0.5 0.5];
            butcher.c = [(3-sqrt(3))/6;(3+sqrt(3))/6];
            butcher.A = [1/4 (3-2*sqrt(3))/12;(3+2*sqrt(3))/12 1/4];

            for k = 2:length(app.Ti)
                h = app.Ti(k) - app.Ti(k-1);
                
                [K,fcount] = K_solve(app,butcher,u(k-1),app.Ti(k),app.Ti(k-1));
                Z = 0;
                for j = 1:numel(butcher.b)
                    Z = Z+butcher.b(j)*K(j);
                end
                u(k) = u(k-1)+(app.Ti(k)-app.Ti(k-1))*Z;
                f_val = f_val + fcount ; 
            end
            y_num = u;
        end

        function [K,fcount] = K_solve(app,butcher,u_prev,Ti_k,Ti_prev)
            st = numel(butcher.b);%number of stages
            h = Ti_k-Ti_prev;
            itmax = min(1.e4,100*length(u_prev));
            toll = 1.e-5;
            K = zeros(st,1);
            stop_rule = 1;
            n_it = 0;
            fcount=0;
            while stop_rule
                n_it = n_it+1;
                b = -K+app.handleFunction(Ti_prev+h*butcher.c,u_prev+h*butcher.A*K);
                Jv = app.df(Ti_prev+h*butcher.c,u_prev+h*butcher.A*K);
                fcount = fcount + 2;
                M = eye(st)-(h*(butcher.A).*Jv);
                s = M\b;
                K = K+s;
                stop_rule = norm(s)>toll && n_it<itmax;
            end
        end

        function[y_num,f_val] = PECE3(app)

            [v, num_fval_AB] = AB3(app);
            [u, num_fval_AM] = AM3(app, v);

            y_num = u;
            f_val = num_fval_AM+num_fval_AB;
        end

        function [u, num_fval] = AB3(app)
            N = numel(app.Ti);
            u(:,1) = app.Y0;
            f0 = app.handleFunction(app.Ti(1),app.Y0);

            [V,fval_1] = RK3(app,u(:,1),app.Ti(1:2));
            u(:,2) = V(:,end);
            f1 = app.handleFunction(app.Ti(2),u(:,2));
            [V,fval_2] = RK3(app,u(:,2),app.Ti(2:3));
            u(:,3) = V(:,end);
            f2 = app.handleFunction(app.Ti(3),u(:,3));

            num_fval = fval_1+fval_2 + 3;

            for i = 4:N
                u(:,i) = u(:,i-1)+((app.Ti(i)-app.Ti(i-1))/12)*(23*f2-16*f1+5*f0);
                f3 = app.handleFunction(app.Ti(i),u(:,i));
                num_fval = num_fval+1;
                f0 = f1;
                f1 = f2;
                f2 = f3;
            end

        end

        function [u, num_fval] = AM3(app,v)
            N = numel(app.Ti);
            u(:,1) = app.Y0;
            f0 = app.handleFunction(app.Ti(1),app.Y0);

            [V,fval_1] = RK3(app,u(:,1),app.Ti(1:2));
            u(:,2) = V(:,end);
            f1 = app.handleFunction(app.Ti(2),u(:,2));

            num_fval = fval_1 + 2;

            for i = 3:N
                f_new = app.handleFunction(app.Ti(i),v(i));
                u(:,i) = u(:,i-1)+((app.Ti(i)-app.Ti(i-1))/12)*(-f0+8*f1+5*f_new);
                f2 = app.handleFunction(app.Ti(i),u(:,i));
                num_fval = num_fval+2;
                f0 = f1;
                f1 = f2;
            end

        end

        function [u,nfval] = RK3(app,y0,T)
            u(:,1) = y0;
            N = numel(T);
            nfval = 0;
            for n = 2:N
                h = T(n)-T(n-1);
                K1 = app.handleFunction(T(n-1),u(:,n-1));
                K2 = app.handleFunction(T(n-1)+(h/2),u(:,n-1)+(h/2)*K1);
                K3 = app.handleFunction(T(n-1)+h,u(:,n-1)+h*K2);
                u(:,n) = u(:,n-1)+(h/6)*(K1+4*K2+K3);
                nfval = nfval+3;
            end
        end


        function [y_num,f_val] = BDF(app)
            N = numel(app.Ti);
            u(:,1) = app.Y0;
            [V, fval_1] = RK3(app,u(:,1),app.Ti(1:2));
            u(:,2) = V(:,end);
            [V, fval_2] = RK3(app,u(:,2),app.Ti(2:3));
            u(:,3) = V(:,end);
            f_val=fval_1+fval_2;
            for i=4:N
                h = app.Ti(i)-app.Ti(i-1);                
                w = -(18/11)*u(:,i-1) + (9/11)*u(:,i-2) - (2/11)*u(:,i-3);
                [u(:,i),fcount] = BDF_nwt3(app,u(:,i-1),w,app.Ti(i),h);
                f_val = f_val + fcount;
            end
            y_num = u;
        end

        function [u,fcount] = BDF_nwt3(app,u_prev,w,t,h)
            itmax = 1e4;
            toll = 1e-5;
            u = u_prev;
            stop_rule = 1;
            n_it = 0;
            fcount = 0;
            while stop_rule
                n_it = n_it + 1;
                J = 1 - (6/11)*h*app.df(t,u);
                b = -(u - (6/11)*h*app.handleFunction(t,u) + w);
                fcount = fcount + 2;
                s = J \ b;
                u = u + s;
                stop_rule = norm(s) > toll && n_it < itmax;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveOdeButton
        function SolveOdeButtonPushed(app, event)

            updateplot(app);

        end

        % Button pushed function: ExplicitEulerButton
        function ExplicitEulerButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.E_euler();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti, y_num,'red--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Explicit Euler Method Solution');
            hold(app.UIAxes,'off')
            app.fval_EEulerTextArea.Value = sprintf('%d',f_val);
            app.N_EEulerTextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.E_euler();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'red','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^1,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Explicit Euler Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^1')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'red','LineWidth', 1.5);
            title(app.UIAxes_3, 'Explicit Euler Computational Complexity');


        end

        % Button pushed function: ImplicitEulerButton
        function ImplicitEulerButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.I_euler();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'blue--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Implicit Euler Method Solution');
            hold(app.UIAxes,'off')
            app.fval_IEulerTextArea.Value = sprintf('%d',f_val);
            app.N_IEulerTextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.I_euler();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'blue','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^1,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Implicit Euler Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^1')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'blue','LineWidth', 1.5);
            title(app.UIAxes_3, 'Implicit Euler Computational Complexity');


        end

        % Button pushed function: TrapezoidButton
        function TrapezoidButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.Trapezoid_method();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'green--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Trapezoid Method Solution');
            hold(app.UIAxes,'off')
            app.fval_TrapezoidTextArea.Value = sprintf('%d',f_val);
            app.N_TrapezoidTextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.Trapezoid_method();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'green','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Trapezoid Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^2')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'green','LineWidth', 1.5);
            title(app.UIAxes_3, 'Trapezoid method Computational Complexity');


        end

        % Button pushed function: ERK2Button
        function ERK2ButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.ERK2();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'cyan--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK2 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK2TextArea.Value = sprintf('%d',f_val);
            app.N_ERK2TextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
           
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK2();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
               
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'cyan','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK2 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^2')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'cyan','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK2 Computational Complexity');


        end

        % Button pushed function: ERK3Button
        function ERK3ButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.ERK3();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'magenta--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK3 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK3TextArea.Value = sprintf('%d',f_val);
            app.N_ERK3TextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK3();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'magenta','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^3,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK3 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'magenta','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK3 Computational Complexity');


        end

        % Button pushed function: ERK4Button
        function ERK4ButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.ERK4();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'black--','LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK4 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK4TextArea.Value = sprintf('%d',f_val);
            app.N_ERK4TextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK4();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'black','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^4,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK4 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^4')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'black','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK4 Computational Complexity');


        end

        % Button pushed function: IRKButton
        function IRKButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.IRK();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'IRK Solution');
            hold(app.UIAxes,'off')
            app.fval_IRKTextArea.Value = sprintf('%d',f_val);
            app.N_IRKTextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.IRK();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^4, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'IRK Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^4')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            title(app.UIAxes_3, 'IRK Computational Complexity');


        end

        % Button pushed function: PECE3Button
        function PECE3ButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.PECE3();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'PECE method Solution');
            hold(app.UIAxes,'off')
            app.fval_PECE3TextArea.Value = sprintf('%d',f_val);
            app.N_PECE3TextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.PECE3();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^3, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'PECE Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            title(app.UIAxes_3, 'PECE Method Computational Complexity');

            
        end

        % Button pushed function: BDFButton
        function BDFButtonPushed(app, event)
            % We reset the parameters 
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            [y_num, f_val] = app.BDF();

            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'BDF Solution');
            hold(app.UIAxes,'off')
            app.fval_BDFTextArea.Value = sprintf('%d',f_val);
            app.N_BDFTextArea.Value = sprintf('%d',app.NumSteps);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.NumSteps;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.BDF();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^3, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'BDF Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            title(app.UIAxes_3, 'BDF Method Computational Complexity');


        end

        % Value changing function: sliderE_eulerSlider
        function sliderE_eulerSliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % Explicit Euler changing N value
            app.N_EE = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_EE);
            [y_num, f_val] = app.E_euler();
            plot(app.UIAxes,app.Ti, y_num,'red--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Explicit Euler Method Solution');
            hold(app.UIAxes,'off')
            app.fval_EEulerTextArea.Value = sprintf('%d',f_val);
            app.N_EEulerTextArea.Value = sprintf('%d',app.N_EE);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_EE;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.E_euler();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];    
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'red','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^1,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Explicit Euler Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^1')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'red','LineWidth', 1.5);
            title(app.UIAxes_3, 'Explicit Euler Computational Complexity');


        end

        % Value changing function: sliderI_eulerSlider
        function sliderI_eulerSliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % Implicit Euler changing N value
            app.N_IE = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_IE);
            [y_num, f_val] = app.I_euler();
            plot(app.UIAxes,app.Ti,y_num,'blue--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Implicit Euler Method Solution');
            hold(app.UIAxes,'off')
            app.fval_IEulerTextArea.Value = sprintf('%d',f_val);
            app.N_IEulerTextArea.Value = sprintf('%d',app.N_IE);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_IE;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.I_euler();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
                
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'blue','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^1,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Implicit Euler Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^1')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'blue','LineWidth', 1.5);
            title(app.UIAxes_3, 'Implicit Euler Computational Complexity');


        end

        % Value changing function: sliderTrapezoidSlider
        function sliderTrapezoidSliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % Trapezoid method changing N value
            app.N_T = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_T);
            [y_num, f_val] = app.Trapezoid_method();
            plot(app.UIAxes,app.Ti,y_num,'green--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'Trapezoid Method Solution');
            hold(app.UIAxes,'off')
            app.fval_TrapezoidTextArea.Value = sprintf('%d',f_val);
            app.N_TrapezoidTextArea.Value = sprintf('%d',app.N_T);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_T;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.Trapezoid_method();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'green','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Trapezoid Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^2')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'green','LineWidth', 1.5);
            title(app.UIAxes_3, 'Trapezoid Method Computational Complexity');


        end

        % Value changed function: slider_ERK2Slider
        function slider_ERK2SliderValueChanged(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % ERK2 changing N value
            app.N_ERK2 = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_ERK2);
            [y_num, f_val] = app.ERK2();
            plot(app.UIAxes,app.Ti,y_num,'cyan--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK2 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK2TextArea.Value = sprintf('%d',f_val);
            app.N_ERK2TextArea.Value = sprintf('%d',app.N_ERK2);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_ERK2;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK2();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'cyan','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK2 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^2')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'cyan','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK2 Computational Complexity');


        end

        % Value changed function: slider_ERK3Slider
        function slider_ERK3SliderValueChanged(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % ERK3 changing N value
            app.N_ERK3 = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_ERK3);
            [y_num, f_val] = app.ERK3();
            plot(app.UIAxes,app.Ti,y_num,'magenta--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK3 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK3TextArea.Value = sprintf('%d',f_val);
            app.N_ERK3TextArea.Value = sprintf('%d',app.N_ERK3);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_ERK3;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK3();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'magenta','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^3,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK3 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'magenta','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK3 Computational Complexity');


        end

        % Value changed function: slider_ERK4Slider
        function slider_ERK4SliderValueChanged(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % ERK4 changing N value
            app.N_ERK4 = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_ERK4);
            [y_num, f_val] = app.ERK4();
            plot(app.UIAxes,app.Ti,y_num,'black--','LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'ERK4 Solution');
            hold(app.UIAxes,'off')
            app.fval_ERK4TextArea.Value = sprintf('%d',f_val);
            app.N_ERK4TextArea.Value = sprintf('%d',app.N_ERK4);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_ERK4;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.ERK4();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'black','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^4,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'ERK4 Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^4')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'black','LineWidth', 1.5);
            title(app.UIAxes_3, 'ERK4 Computational Complexity');


        end

        % Value changing function: slider_IRKSlider
        function slider_IRKSliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % IRK changing N value
            app.N_IRK = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_IRK);
            [y_num, f_val] = app.IRK();
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'IRK Solution');
            hold(app.UIAxes,'off')
            app.fval_IRKTextArea.Value = sprintf('%d',f_val);
            app.N_IRKTextArea.Value = sprintf('%d',app.N_IRK);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_IRK;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.IRK();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^4, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'IRK Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^4')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0 0.4470 0.7410],'LineWidth', 1.5);
            title(app.UIAxes_3, 'IRK Method Computational Complexity');


        end

        % Value changing function: slider_PECE3Slider
        function slider_PECE3SliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % PECE3 changing N value
            app.N_PECE3 = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_PECE3);
            [y_num, f_val] = app.PECE3();
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'PECE Solution');
            hold(app.UIAxes,'off')
            app.fval_PECE3TextArea.Value = sprintf('%d',f_val);
            app.N_PECE3TextArea.Value = sprintf('%d',app.N_PECE3);

            % convergence and computational complexity plot
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_PECE3;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.PECE3();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^3, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'PECE Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0.4940 0.1840 0.5560],'LineWidth', 1.5);
            title(app.UIAxes_3, 'PECE Method Computational Complexity');


        end

        % Value changing function: slider_BDFSlider
        function slider_BDFSliderValueChanging(app, event)
            changingValue = event.Value;
            % We reset the value for the plot of the true solution
            N = app.NEditField.Value;
            app.NumSteps = N;
            app.TimeInterval = [app.LeftboundaryEditField.Value,app.RightboundaryEditField.Value];
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.NumSteps);

            % solution plot
            plot(app.UIAxes, app.Ti, app.Ytrue(app.Ti), 'yellow','LineWidth', 1.5);
            hold(app.UIAxes,'on')

            % BDF changing N value
            app.N_BDF = changingValue;
            app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2),app.N_BDF);
            [y_num, f_val] = app.BDF();
            plot(app.UIAxes,app.Ti,y_num,'--','Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            xlim(app.UIAxes, [app.TimeInterval(1), app.TimeInterval(2)]);
            ylim(app.UIAxes, [min(app.Ytrue(app.Ti)), max(app.Ytrue(app.Ti))]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Solution');
            title(app.UIAxes, 'BDF Solution');
            hold(app.UIAxes,'off')
            app.fval_BDFTextArea.Value = sprintf('%d',f_val);
            app.N_BDFTextArea.Value = sprintf('%d',app.N_BDF);

            % convergence and computational complexity plots
            u_true = app.Ytrue(app.Ti);
            Aerr = norm(u_true-y_num);
            Rerr = Aerr/norm(u_true);
            h = app.Ti(2)-app.Ti(1);
            N = app.N_BDF;
            Data = [N,h,Aerr,Rerr,f_val];
            for n=1:3
                N = 2*N;
                app.Ti = linspace(app.TimeInterval(1),app.TimeInterval(2), N);
                [y_num, f_val] = app.BDF();
                u_true = app.Ytrue(app.Ti);
                Aerr = norm(u_true-y_num);
                Rerr = Aerr/norm(u_true);
                h = app.Ti(2)-app.Ti(1);
                Data = [Data;[N,h,Aerr,Rerr,f_val]];
            end

            loglog(app.UIAxes_2, Data(:,2), Data(:,4),'Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on')
            loglog(app.UIAxes_2, Data(:,2), Data(:,2).^3, 'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2, 'off')
            title(app.UIAxes_2, 'BDF Method convergence');
            legend(app.UIAxes_2,'Rel Err','h^3')

            plot(app.UIAxes_3, Data(:,1), Data(:,5),'Color',[0.9290 0.6940 0.1250],'LineWidth', 1.5);
            title(app.UIAxes_3, 'BDF Method Computational Complexity');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Solution plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Solution Y(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Convergence plot')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Complexity plot')
            xlabel(app.UIAxes_3, 'N')
            ylabel(app.UIAxes_3, 'fval')
            zlabel(app.UIAxes_3, 'Z')
            app.SolveOdeButton = uibutton(app.UIFigure, 'push');
            app.SolveOdeButton.ButtonPushedFcn = createCallbackFcn(app, @SolveOdeButtonPushed, true);
            app.SolveOdeButton.Text = 'SolveOde';
            app.ExplicitEulerButton = uibutton(app.UIFigure, 'push');
            app.ExplicitEulerButton.ButtonPushedFcn = createCallbackFcn(app, @ExplicitEulerButtonPushed, true);
            app.ExplicitEulerButton.Text = 'Explicit Euler';
            app.ImplicitEulerButton = uibutton(app.UIFigure, 'push');
            app.ImplicitEulerButton.ButtonPushedFcn = createCallbackFcn(app, @ImplicitEulerButtonPushed, true);
            app.ImplicitEulerButton.Text = 'Implicit Euler';
            app.TrapezoidButton = uibutton(app.UIFigure, 'push');
            app.TrapezoidButton.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidButtonPushed, true);
            app.TrapezoidButton.Text = 'Trapezoid';
            app.LeftboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.LeftboundaryEditFieldLabel.Text = 'Left boundary';
            app.LeftboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.RightboundaryEditFieldLabel.Text = 'Right boundary';
            app.InitialconditionEditFieldLabel = uilabel(app.UIFigure);
            app.InitialconditionEditFieldLabel.Text = 'Initial condition';
            app.InitialconditionEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 100;
            app.sliderE_eulerSliderLabel = uilabel(app.UIFigure);
            app.sliderE_eulerSliderLabel.Text = 'slider E_euler';
            app.sliderE_eulerSlider = uislider(app.UIFigure);
            app.sliderE_eulerSlider.Limits = [10 500];
            app.sliderE_eulerSlider.MajorTicks = [10 100 200 300 400 500];
            app.sliderE_eulerSlider.ValueChangingFcn = createCallbackFcn(app, @sliderE_eulerSliderValueChanging, true);
            app.sliderE_eulerSlider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.sliderE_eulerSlider.Value = 50;
            app.sliderI_eulerSlider = uislider(app.UIFigure);
            app.sliderI_eulerSlider.Limits = [10 500];
            app.sliderI_eulerSlider.MajorTicks = [10 100 200 300 400 500];
            app.sliderI_eulerSlider.ValueChangingFcn = createCallbackFcn(app, @sliderI_eulerSliderValueChanging, true);
            app.sliderI_eulerSlider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.sliderI_eulerSlider.Value = 50;
            app.sliderI_eulerSliderLabel = uilabel(app.UIFigure);
            app.sliderI_eulerSliderLabel.Text = 'slider I_euler';
            app.sliderTrapezoidSliderLabel = uilabel(app.UIFigure);
            app.sliderTrapezoidSliderLabel.Text = 'slider Trapezoid';
            app.sliderTrapezoidSlider = uislider(app.UIFigure);
            app.sliderTrapezoidSlider.Limits = [10 500];
            app.sliderTrapezoidSlider.MajorTicks = [10 100 200 300 400 500];
            app.sliderTrapezoidSlider.ValueChangingFcn = createCallbackFcn(app, @sliderTrapezoidSliderValueChanging, true);
            app.sliderTrapezoidSlider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.sliderTrapezoidSlider.Value = 50;
            app.N_EEulerTextAreaLabel = uilabel(app.UIFigure);
            app.N_EEulerTextAreaLabel.Text = 'N_EEuler';
            app.N_EEulerTextArea = uitextarea(app.UIFigure);
            app.fval_EEulerTextAreaLabel = uilabel(app.UIFigure);
            app.fval_EEulerTextAreaLabel.Text = 'fval_EEuler';
            app.fval_EEulerTextArea = uitextarea(app.UIFigure);
            app.N_IEulerTextAreaLabel = uilabel(app.UIFigure);
            app.N_IEulerTextAreaLabel.Text = 'N_IEuler';
            app.N_IEulerTextArea = uitextarea(app.UIFigure);
            app.fval_IEulerTextAreaLabel = uilabel(app.UIFigure);
            app.fval_IEulerTextAreaLabel.Text = 'fval_IEuler';
            app.fval_IEulerTextArea = uitextarea(app.UIFigure);
            app.N_TrapezoidTextAreaLabel = uilabel(app.UIFigure);
            app.N_TrapezoidTextAreaLabel.Text = 'N_Trapezoid';
            app.N_TrapezoidTextArea = uitextarea(app.UIFigure);
            app.fval_TrapezoidTextAreaLabel = uilabel(app.UIFigure);
            app.fval_TrapezoidTextAreaLabel.Text = 'fval_Trapezoid';
            app.fval_TrapezoidTextArea = uitextarea(app.UIFigure);
            app.ERK2Button = uibutton(app.UIFigure, 'push');
            app.ERK2Button.ButtonPushedFcn = createCallbackFcn(app, @ERK2ButtonPushed, true);
            app.ERK2Button.Text = 'ERK2';
            app.slider_ERK2SliderLabel = uilabel(app.UIFigure);
            app.slider_ERK2SliderLabel.Text = 'slider_ERK2';
            app.slider_ERK2Slider = uislider(app.UIFigure);
            app.slider_ERK2Slider.Limits = [10 500];
            app.slider_ERK2Slider.MajorTicks = [10 100 200 300 400 500];
            app.slider_ERK2Slider.ValueChangedFcn = createCallbackFcn(app, @slider_ERK2SliderValueChanged, true);
            app.slider_ERK2Slider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_ERK2Slider.Value = 10;
            app.N_ERK2TextAreaLabel = uilabel(app.UIFigure);
            app.N_ERK2TextAreaLabel.Text = 'N_ERK2';
            app.N_ERK2TextArea = uitextarea(app.UIFigure);
            app.fval_ERK2TextAreaLabel = uilabel(app.UIFigure);
            app.fval_ERK2TextAreaLabel.Text = 'fval_ERK2';
            app.fval_ERK2TextArea = uitextarea(app.UIFigure);
            app.ERK3Button = uibutton(app.UIFigure, 'push');
            app.ERK3Button.ButtonPushedFcn = createCallbackFcn(app, @ERK3ButtonPushed, true);
            app.ERK3Button.Text = 'ERK3';
            app.slider_ERK3SliderLabel = uilabel(app.UIFigure);
            app.slider_ERK3SliderLabel.Text = 'slider_ERK3';
            app.slider_ERK3Slider = uislider(app.UIFigure);
            app.slider_ERK3Slider.Limits = [10 500];
            app.slider_ERK3Slider.MajorTicks = [10 100 200 300 400 500];
            app.slider_ERK3Slider.ValueChangedFcn = createCallbackFcn(app, @slider_ERK3SliderValueChanged, true);
            app.slider_ERK3Slider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_ERK3Slider.Value = 10;
            app.N_ERK3TextAreaLabel = uilabel(app.UIFigure);
            app.N_ERK3TextAreaLabel.Text = 'N_ERK3';
            app.N_ERK3TextArea = uitextarea(app.UIFigure);
            app.fval_ERK3TextAreaLabel = uilabel(app.UIFigure);
            app.fval_ERK3TextAreaLabel.Text = 'fval_ERK3';
            app.fval_ERK3TextArea = uitextarea(app.UIFigure);
            app.ERK4Button = uibutton(app.UIFigure, 'push');
            app.ERK4Button.ButtonPushedFcn = createCallbackFcn(app, @ERK4ButtonPushed, true);
            app.ERK4Button.Text = 'ERK4';
            app.slider_ERK4SliderLabel = uilabel(app.UIFigure);
            app.slider_ERK4SliderLabel.Text = 'slider_ERK4';
            app.slider_ERK4Slider = uislider(app.UIFigure);
            app.slider_ERK4Slider.Limits = [10 500];
            app.slider_ERK4Slider.MajorTicks = [10 100 200 300 400 500];
            app.slider_ERK4Slider.ValueChangedFcn = createCallbackFcn(app, @slider_ERK4SliderValueChanged, true);
            app.slider_ERK4Slider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_ERK4Slider.Value = 10;
            app.N_ERK4TextAreaLabel = uilabel(app.UIFigure);
            app.N_ERK4TextAreaLabel.Text = 'N_ERK4';
            app.N_ERK4TextArea = uitextarea(app.UIFigure);
            app.fval_ERK4TextAreaLabel = uilabel(app.UIFigure);
            app.fval_ERK4TextAreaLabel.Text = 'fval_ERK4';
            app.fval_ERK4TextArea = uitextarea(app.UIFigure);
            app.IRKButton = uibutton(app.UIFigure, 'push');
            app.IRKButton.ButtonPushedFcn = createCallbackFcn(app, @IRKButtonPushed, true);
            app.IRKButton.Text = 'IRK';
            app.slider_IRKSliderLabel = uilabel(app.UIFigure);
            app.slider_IRKSliderLabel.Text = 'slider_IRK';
            app.slider_IRKSlider = uislider(app.UIFigure);
            app.slider_IRKSlider.Limits = [10 500];
            app.slider_IRKSlider.MajorTicks = [10 100 200 300 400 500];
            app.slider_IRKSlider.ValueChangingFcn = createCallbackFcn(app, @slider_IRKSliderValueChanging, true);
            app.slider_IRKSlider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_IRKSlider.Value = 10;
            app.N_IRKTextAreaLabel = uilabel(app.UIFigure);
            app.N_IRKTextAreaLabel.Text = 'N_IRK';
            app.N_IRKTextArea = uitextarea(app.UIFigure);
            app.fval_IRKTextAreaLabel = uilabel(app.UIFigure);
            app.fval_IRKTextAreaLabel.Text = 'fval_IRK';
            app.fval_IRKTextArea = uitextarea(app.UIFigure);
            app.PECE3Button = uibutton(app.UIFigure, 'push');
            app.PECE3Button.ButtonPushedFcn = createCallbackFcn(app, @PECE3ButtonPushed, true);
            app.PECE3Button.Text = 'PECE3';
            app.slider_PECE3SliderLabel = uilabel(app.UIFigure);
            app.slider_PECE3SliderLabel.Text = 'slider_PECE3';
            app.slider_PECE3Slider = uislider(app.UIFigure);
            app.slider_PECE3Slider.Limits = [10 500];
            app.slider_PECE3Slider.MajorTicks = [10 100 200 300 400 500];
            app.slider_PECE3Slider.ValueChangingFcn = createCallbackFcn(app, @slider_PECE3SliderValueChanging, true);
            app.slider_PECE3Slider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_PECE3Slider.Value = 10;
            app.N_PECE3TextAreaLabel = uilabel(app.UIFigure);
            app.N_PECE3TextAreaLabel.Text = 'N_PECE3';
            app.N_PECE3TextArea = uitextarea(app.UIFigure);
            app.fval_PECE3TextAreaLabel = uilabel(app.UIFigure);
            app.fval_PECE3TextAreaLabel.Text = 'fval_PECE3';
            app.fval_PECE3TextArea = uitextarea(app.UIFigure);
            app.BDFButton = uibutton(app.UIFigure, 'push');
            app.BDFButton.ButtonPushedFcn = createCallbackFcn(app, @BDFButtonPushed, true);
            app.BDFButton.Text = 'BDF';
            app.slider_BDFSliderLabel = uilabel(app.UIFigure);
            app.slider_BDFSliderLabel.Text = 'slider_BDF';
            app.slider_BDFSlider = uislider(app.UIFigure);
            app.slider_BDFSlider.Limits = [10 500];
            app.slider_BDFSlider.MajorTicks = [10 100 200 300 400 500];
            app.slider_BDFSlider.ValueChangingFcn = createCallbackFcn(app, @slider_BDFSliderValueChanging, true);
            app.slider_BDFSlider.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.slider_BDFSlider.Value = 10;
            app.N_BDFTextAreaLabel = uilabel(app.UIFigure);
            app.N_BDFTextAreaLabel.Text = 'N_BDF';
            app.N_BDFTextArea = uitextarea(app.UIFigure);
            app.fval_BDFTextAreaLabel = uilabel(app.UIFigure);
            app.fval_BDFTextAreaLabel.Text = 'fval_BDF';
            app.fval_BDFTextArea = uitextarea(app.UIFigure);
            app.SelectEquationDropDownLabel = uilabel(app.UIFigure);
            app.SelectEquationDropDownLabel.Text = {'Select'; 'Equation'};
            app.SelectEquationDropDown = uidropdown(app.UIFigure);
            app.SelectEquationDropDown.Items = {'y*(2+y)*(2-y)', 'y+t', '-5*t*y^2+(5/t)-(1/t^2)', '-100*(y-sin(t))', '2*t*y', '...'};
            app.SelectEquationDropDown.Value = 'y*(2+y)*(2-y)';
            app.yourequationLabel = uilabel(app.UIFigure);
            app.yourequationLabel.Text = 'sym';
            app.symEditField = uieditfield(app.UIFigure, 'text');
            app.handleEditFieldLabel = uilabel(app.UIFigure);
            app.handleEditFieldLabel.Text = 'handle';
            app.handleEditField = uieditfield(app.UIFigure, 'text');
            app.ChooseYourEquationLabel = uilabel(app.UIFigure);
            app.ChooseYourEquationLabel.Text = {'Choose'; 'Your'; 'Equation'};
            app.orLabel = uilabel(app.UIFigure);
            app.orLabel.Text = 'or';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1_A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end